classdef OffshoreProje < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculateSubmergedHeightButton  matlab.ui.control.Button
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        Densitykgm3EditField            matlab.ui.control.NumericEditField
        WeightofPlatform103TonsSliderLabel  matlab.ui.control.Label
        WeightofPlatform103TonsSlider   matlab.ui.control.Slider
        TemperatureCKnobLabel           matlab.ui.control.Label
        TemperatureCKnob                matlab.ui.control.Knob
        SalinitypptKnobLabel            matlab.ui.control.Label
        SalinitypptKnob                 matlab.ui.control.Knob
        HeightSliderLabel               matlab.ui.control.Label
        HeightSlider                    matlab.ui.control.Slider
        Image                           matlab.ui.control.Image
        EditField                       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function Seawaterdensity(app,temperature,salinity)
            A= 8.24493e-1- 4.0899e-3*temperature+ 7.6438e-5*temperature^2 ...
                - 8.2467e-7*temperature^3+ 5.3875e-9*temperature^4;
            B= -5.72466e-3 + 1.0227e-4*temperature- 1.6546e-6*temperature^2;
            C= 4.8314e-4;
            %Calculating the water density
            water_density= 999.842594+ 6.793952e-2*temperature-9.095290e-3 ...
                *temperature^2+1.001685e-4*temperature^3-1.120083e-6* ...
                temperature^4+ 6.536336e-9*temperature^5;
            
            %Calculating the sea water density
            app.Densitykgm3EditField.Value= round(water_density+ A*salinity + B*...
                (salinity^1.5)+C*(salinity^2),2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SalinitypptKnob, TemperatureCKnob
        function TemperatureCKnobValueChanged(app, event)
            temperature = app.TemperatureCKnob.Value;
            salinity= app.SalinitypptKnob.Value;
            Seawaterdensity(app,temperature,salinity)
        end

        % Button pushed function: CalculateSubmergedHeightButton
        function CalculateSubmergedHeightButtonPushed(app, event)
            syms y(x) h;
            y(x)=piecewise(x>=0 & x<=5,pi*(50-x)^2,x>5 & x<=17,pi*45^2,x>17 & x<=22,pi*(45+x)^2);
            a=round(app.WeightofPlatform103TonsSlider.Value/app.Densitykgm3EditField.Value,8);
            m=a*10^6;
            F=int(y(x),0,h)==m;
            try
                app.EditField.Value=round(double(solve(F,h)),4);
            catch
                msgbox({'SSP sank!!!';'Because needed height exceeded the height of SSP'},'SANK!!!','error');
            end
            app.HeightSlider.Value=app.EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateSubmergedHeightButton = uibutton(app.UIFigure, 'push');
            app.CalculateSubmergedHeightButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSubmergedHeightButtonPushed, true);
            app.CalculateSubmergedHeightButton.Text = 'Calculate Submerged Height';
            app.Densitykgm3EditFieldLabel = uilabel(app.UIFigure);
            app.Densitykgm3EditFieldLabel.Text = 'Density, kg/ m3';
            app.Densitykgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Densitykgm3EditField.Limits = [100 Inf];
            app.Densitykgm3EditField.ValueDisplayFormat = '%.2f';
            app.Densitykgm3EditField.Value = 1000;
            app.WeightofPlatform103TonsSliderLabel = uilabel(app.UIFigure);
            app.WeightofPlatform103TonsSliderLabel.Text = 'Weight of Platform, 10^3 Tons';
            app.WeightofPlatform103TonsSlider = uislider(app.UIFigure);
            app.WeightofPlatform103TonsSlider.Limits = [10 250];
            app.WeightofPlatform103TonsSlider.Value = 180;
            app.TemperatureCKnobLabel = uilabel(app.UIFigure);
            app.TemperatureCKnobLabel.Text = 'Temperature, C';
            app.TemperatureCKnob = uiknob(app.UIFigure, 'continuous');
            app.TemperatureCKnob.Limits = [0 45];
            app.TemperatureCKnob.ValueChangedFcn = createCallbackFcn(app, @TemperatureCKnobValueChanged, true);
            app.SalinitypptKnobLabel = uilabel(app.UIFigure);
            app.SalinitypptKnobLabel.Text = 'Salinity, ppt';
            app.SalinitypptKnob = uiknob(app.UIFigure, 'continuous');
            app.SalinitypptKnob.Limits = [0.5 43];
            app.SalinitypptKnob.ValueChangedFcn = createCallbackFcn(app, @TemperatureCKnobValueChanged, true);
            app.SalinitypptKnob.Value = 0.5;
            app.HeightSliderLabel = uilabel(app.UIFigure);
            app.HeightSliderLabel.Text = 'Height ';
            app.HeightSlider = uislider(app.UIFigure);
            app.HeightSlider.Limits = [0 27];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Sevan-Accomodation-unit.jpg';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%.3f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OffshoreProje

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end